classdef AtwoodApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        Button                      matlab.ui.control.StateButton
        LeftMassSliderLabel         matlab.ui.control.Label
        LeftMassSlider              matlab.ui.control.Slider
        RightMassSliderLabel        matlab.ui.control.Label
        RightMassSlider             matlab.ui.control.Slider
        LeftRopeLengthSliderLabel   matlab.ui.control.Label
        LeftRopeLengthSlider        matlab.ui.control.Slider
        PulleyMassSliderLabel       matlab.ui.control.Label
        PulleyMassSlider            matlab.ui.control.Slider
        RopeMassSliderLabel         matlab.ui.control.Label
        RopeMassSlider              matlab.ui.control.Slider
        RightRopeLengthSliderLabel  matlab.ui.control.Label
        RightRopeLengthSlider       matlab.ui.control.Slider
        RadiusSliderLabel           matlab.ui.control.Label
        RadiusSlider                matlab.ui.control.Slider
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LeftMassSlider
        function LeftMassSliderValueChanged(app, event)
            value = app.LeftMassSlider.Value;
            
        end

        % Value changed function: RightMassSlider
        function RightMassSliderValueChanged(app, event)
            value = app.RightMassSlider.Value;
            
        end

        % Value changed function: PulleyMassSlider
        function PulleyMassSliderValueChanged(app, event)
            value = app.PulleyMassSlider.Value;
            
        end

        % Value changed function: RopeMassSlider
        function RopeMassSliderValueChanged(app, event)
            value = app.RopeMassSlider.Value;
            
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            %Initialization of Variables
            radius = app.RadiusSlider.Value;
            
            mLeft = app.LeftMassSlider.Value;
            mRight = app.RightMassSlider.Value;
            mPulley = app.PulleyMassSlider.Value;
            mRope = app.RopeMassSlider.Value;
            
            leftLength = app.LeftRopeLengthSlider.Value;
            rightLength = app.RightRopeLengthSlider.Value;
            height = 3;
            length = leftLength + rightLength + pi*radius;
            lambda = mRope/length;
            
            a = 0;
            v = 0;
            dx = 0;
            
            a2 = 0;
            v2 = 0;
            dx2 = 0;
            
            %Assigning Axis
            dL = abs(-radius-leftLength-rightLength-height)
            uL = radius +1
            
            
            %app.UIAxes.DataAspectRatio = [1, 1, 1];
            
            
            %Determine Direction of Motion
            if mLeft + leftLength*lambda < mRight + rightLength*lambda 
                switchDirection = -1;
            end 
            if mLeft + leftLength*lambda == mRight + rightLength*lambda 
                switchDirection = 0;
            end 
            if mLeft + leftLength*lambda > mRight + rightLength*lambda
                switchDirection = 1;
            end
            
            switchDirection2 = 1;
            if mLeft > mRight
                switchDirection2 = -1;
            end
            if mLeft == mRight
                switchDirection2 = 0;
            end
            offset = 6*radius;
            stopX = false;
            stopX2 = false;
            for dt = 0:.05: 10
                %Clear Axis
                cla(app.UIAxes,'reset');
                %Set bounds for axes
                axis(app.UIAxes,[-(dL+uL)/2+offset/2 (dL+uL)/2+offset/2 -dL uL+1]);
                axis(app.UIAxes, "off");
                %titles for two systems
                text(app.UIAxes,offset-2*radius,radius*1.3,'Massless Rope and Pulley')
                text(app.UIAxes,-1.75*radius,radius*1.3,'Massive Rope and Pulley')
                %Setting Up Kinematic Equations
                if stopX == false
                    %update acceleration, velocity, and position
                    a = (-mLeft+mRight-2*mRight*lambda-length*mRight + pi*radius*lambda)/(mLeft+mRight+mPulley/2+length*leftLength);
                    v = v+a*dt;
                    dx = dx+v*dt;
                    %Slow Down Time By a Factor of 5;
                    dx = dx/5;
                    xLeft = -leftLength+(dx*switchDirection);
                    xRight = -rightLength-(dx*switchDirection);
                end
                %Plot All Componets of Atwood Machine
                line(app.UIAxes,[radius radius], [0 xRight], 'LineWidth', 4)
                line(app.UIAxes,[-radius -radius], [0 xLeft], 'LineWidth',4)
                rectangle(app.UIAxes,'Position',[-radius -radius 2*radius 2*radius],'Curvature',[1 1], 'LineWidth',3, "FaceColor", 'black');
                rectangle(app.UIAxes,'Position', [radius-height/2 xRight-height height height], 'LineWidth', 3, "FaceColor", 'black');
                rectangle(app.UIAxes,'Position', [-radius-height/2 xLeft-height height height], 'LineWidth', 3, "FaceColor", 'black');
                %Stop Motion Before Mass Hits Pulley
                if stopX2 == false
                    %update acceleration, velocity, and position
                    a2 = (mRight-mLeft)/(mRight+mLeft);
                    v2= v2+a2*dt;
                    dx2 = dx2+v2*dt;
                    %Slow down by factor of 5
                    dx2 = dx2/5;  
                    xLeft1 = -leftLength+(dx2*switchDirection2);
                    xRight1 = -rightLength-(dx2*switchDirection2);
                end
                %Create elements of the system
                line(app.UIAxes,[radius+offset radius+offset], [0 xRight1], 'LineWidth', 2)
                line(app.UIAxes,[-radius+offset -radius+offset], [0 xLeft1], 'LineWidth',2)
                rectangle(app.UIAxes,'Position',[-radius+offset -radius 2*radius 2*radius],'Curvature',[1 1], 'LineWidth',2);
                rectangle(app.UIAxes,'Position', [radius-height/2+offset xRight1-height height height], 'LineWidth', 3, "FaceColor", 'black');
                rectangle(app.UIAxes,'Position', [-radius-height/2+offset xLeft1-height height height], 'LineWidth', 3, "FaceColor", 'black');
                pause(.05);
                
                %stop left system
                if (-rightLength-dx*switchDirection + height/2 >= -radius || -leftLength+dx*switchDirection + height/2 >= -radius)
                    stopX = true;
                end
                %stop right system
                if (-rightLength+height/2-(dx2*switchDirection2) >= -radius || -leftLength+dx2*switchDirection2 + height/2 >= -radius)
                    stopX2 = true;
                end
                %stop everything when both are stopped
                if stopX2 && stopX
                    break;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Atwood Machine')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'Button';
            app.LeftMassSliderLabel = uilabel(app.UIFigure);
            app.LeftMassSliderLabel.Text = 'Left Mass';
            app.LeftMassSlider = uislider(app.UIFigure);
            app.LeftMassSlider.Limits = [1 10];
            app.LeftMassSlider.ValueChangedFcn = createCallbackFcn(app, @LeftMassSliderValueChanged, true);
            app.LeftMassSlider.Value = 3;
            app.RightMassSliderLabel = uilabel(app.UIFigure);
            app.RightMassSliderLabel.Text = 'Right Mass';
            app.RightMassSlider = uislider(app.UIFigure);
            app.RightMassSlider.Limits = [1 10];
            app.RightMassSlider.ValueChangedFcn = createCallbackFcn(app, @RightMassSliderValueChanged, true);
            app.RightMassSlider.Value = 2;
            app.LeftRopeLengthSliderLabel = uilabel(app.UIFigure);
            app.LeftRopeLengthSliderLabel.Text = 'Left Rope Length';
            app.LeftRopeLengthSlider = uislider(app.UIFigure);
            app.LeftRopeLengthSlider.Limits = [10 50];
            app.LeftRopeLengthSlider.Value = 15;
            app.PulleyMassSliderLabel = uilabel(app.UIFigure);
            app.PulleyMassSliderLabel.Text = 'Pulley Mass';
            app.PulleyMassSlider = uislider(app.UIFigure);
            app.PulleyMassSlider.Limits = [1 10];
            app.PulleyMassSlider.ValueChangedFcn = createCallbackFcn(app, @PulleyMassSliderValueChanged, true);
            app.PulleyMassSlider.Value = 1;
            app.RopeMassSliderLabel = uilabel(app.UIFigure);
            app.RopeMassSliderLabel.Text = 'Rope Mass';
            app.RopeMassSlider = uislider(app.UIFigure);
            app.RopeMassSlider.Limits = [1 10];
            app.RopeMassSlider.ValueChangedFcn = createCallbackFcn(app, @RopeMassSliderValueChanged, true);
            app.RopeMassSlider.Value = 1;
            app.RightRopeLengthSliderLabel = uilabel(app.UIFigure);
            app.RightRopeLengthSliderLabel.Text = 'Right Rope Length';
            app.RightRopeLengthSlider = uislider(app.UIFigure);
            app.RightRopeLengthSlider.Limits = [10 50];
            app.RightRopeLengthSlider.Value = 10;
            app.RadiusSliderLabel = uilabel(app.UIFigure);
            app.RadiusSliderLabel.Text = 'Radius';
            app.RadiusSlider = uislider(app.UIFigure);
            app.RadiusSlider.Limits = [1 5];
            app.RadiusSlider.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AtwoodApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end